classdef REST_ls < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlowdetectionAppUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        ChanneltoplotDropDown           matlab.ui.control.DropDown
        ChanneltoplotDropDownLabel      matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        EndEditField_2                  matlab.ui.control.NumericEditField
        EndEditField_2Label             matlab.ui.control.Label
        StartEditField_2                matlab.ui.control.NumericEditField
        StartEditField_2Label           matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        RunEditField                    matlab.ui.control.NumericEditField
        RunEditFieldLabel               matlab.ui.control.Label
        BlockEditField                  matlab.ui.control.NumericEditField
        BlockEditFieldLabel             matlab.ui.control.Label
        SubjectListBox                  matlab.ui.control.ListBox
        SubjectListBoxLabel             matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        rawdataplot                     matlab.ui.control.UIAxes
        PSDTab                          matlab.ui.container.Tab
        SaveButton_3                    matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        GOButton_2                      matlab.ui.control.Button
        GOButton                        matlab.ui.control.Button
        DataDisplayButtonGroup_2        matlab.ui.container.ButtonGroup
        PSDButton_2                     matlab.ui.control.RadioButton
        timeseriesButton_2              matlab.ui.control.RadioButton
        DataButtonGroup                 matlab.ui.container.ButtonGroup
        PSDButton                       matlab.ui.control.RadioButton
        timeseriesButton                matlab.ui.control.RadioButton
        sec2axis                        matlab.ui.control.UIAxes
        sec1axis                        matlab.ui.control.UIAxes
        SWdetectionTab_2                matlab.ui.container.Tab
        PlotSWButton                    matlab.ui.control.Button
        TimebeforeEditField_2           matlab.ui.control.NumericEditField
        TimebeforeEditField_2Label      matlab.ui.control.Label
        TimeafterEditField              matlab.ui.control.NumericEditField
        TimeafterEditFieldLabel         matlab.ui.control.Label
        slopeEditField                  matlab.ui.control.NumericEditField
        slopeEditFieldLabel             matlab.ui.control.Label
        halfwavedurationEditFieldLabel_3  matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        MastoidoppoButton_2             matlab.ui.control.ToggleButton
        MastoidavgButton_2              matlab.ui.control.ToggleButton
        GlobalButton_2                  matlab.ui.control.ToggleButton
        halfwavedurationEditFieldLabel_2  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AllchannelsButton               matlab.ui.control.ToggleButton
        CanonicaldimButton              matlab.ui.control.ToggleButton
        CanonicalsqrButton              matlab.ui.control.ToggleButton
        halfwavedurationEditField_5     matlab.ui.control.NumericEditField
        fullwavedurationEditField       matlab.ui.control.NumericEditField
        fullwavedurationEditFieldLabel  matlab.ui.control.Label
        halfwavedurationEditField_3     matlab.ui.control.NumericEditField
        negativemagEditField            matlab.ui.control.NumericEditField
        negativemagEditFieldLabel       matlab.ui.control.Label
        ClearSWButton                   matlab.ui.control.Button
        CheckBox_4                      matlab.ui.control.CheckBox
        filtermethodDropDown            matlab.ui.control.DropDown
        filtermethodDropDownLabel       matlab.ui.control.Label
        peaktopeakminEditField          matlab.ui.control.NumericEditField
        peaktopeakminEditFieldLabel     matlab.ui.control.Label
        halfwavedurationsEditField      matlab.ui.control.NumericEditField
        halfwavedurationsEditFieldLabel  matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        SaveSWButton                    matlab.ui.control.Button
        RunSWdetectionButton            matlab.ui.control.Button
        Fp2CheckBox                     matlab.ui.control.CheckBox
        Fp1CheckBox                     matlab.ui.control.CheckBox
        E2CheckBox                      matlab.ui.control.CheckBox
        E1CheckBox                      matlab.ui.control.CheckBox
        RunBlinkDetectionButton         matlab.ui.control.Button
        highpassfilterEditField         matlab.ui.control.NumericEditField
        highpassfilterEditFieldLabel    matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        lowpassfilterEditField_2        matlab.ui.control.NumericEditField
        lowpassfilterEditField_2Label   matlab.ui.control.Label
        UserdefinedparametersTextArea_2  matlab.ui.control.TextArea
        UserdefinedparametersTextArea_2Label  matlab.ui.control.Label
        UsepredefinedsettingsButtonGroup_2  matlab.ui.container.ButtonGroup
        Hung2013Button                  matlab.ui.control.RadioButton
        Fattinger2017Button             matlab.ui.control.RadioButton
        Staresina2015Button_2           matlab.ui.control.RadioButton
        Ngo2015Button_2                 matlab.ui.control.RadioButton
        AASMButton_2                    matlab.ui.control.RadioButton
        Massimini2004Button_2           matlab.ui.control.RadioButton
        SWDisplay                       matlab.ui.control.UIAxes
        BlinkDisplay                    matlab.ui.control.UIAxes
        ResultTab                       matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        ContextMenu                     matlab.ui.container.ContextMenu
        passbandstopbandMenu            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        RAW     % raw EEG_overnight
        subjID  % which subject data to use
        runID
        blockID
        rawdat
        fs = 500;
        taskstart1
        taskstart2
        taskend1
        taskend2
        psd1
        psd2
        x
        y
        type
        ch_plt
        usePRED
        useFREE
        Eyechn = [];
        Eye_proc
        BlinkThresh = 1.5;
        filt
        filttype
        filt_dat
        run_detect_ch = 9;
        slow_waves
        slow_waves_MNP
        slow_waves_ZX
        FP_rate
        FP_rate_MNP
        FP_rate_ZX
        filt_method
        reref
        cano_dat
        time_a8
        time_b4
        chlist
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.mat'});
            load([pathname, filename]);
            app.RAW = eegnew;
            app.chlist = eegnew.chlist;
        end

        % Value changed function: SubjectListBox
        function SubjectListBoxValueChanged(app, event)
            value = app.SubjectListBox.Value;
            app.subjID = value;
        end

        % Callback function: BlinkDisplay, BlockEditField, SWDisplay
        function BlockEditFieldValueChanged(app, event)
            value = app.BlockEditField.Value;
            app.blockID = value;
            
        end

        % Value changed function: RunEditField
        function RunEditFieldValueChanged(app, event)
            value = app.RunEditField.Value;
            app.runID = value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            run = find(contains(app.RAW.subj,app.subjID) & str2double(cell2mat(app.RAW.nblock))==app.blockID);
            try
                nr = run(app.runID);
                app.rawdat = cell2mat(app.RAW.data(nr));
            catch
                nr = run;
                app.rawdat = cell2mat(app.RAW.data(nr));
            end
            %             plot(app.rawdataplot,app.rawdat(1,:))
            dat_plt = app.rawdat(app.ch_plt,:);
            params = struct;
            movingwin = [2.5 1];
            clear S t f
            params.Fs = app.fs;params.tapers=[3 5];
            [S, t, f] = mtspecgramc(dat_plt,movingwin,params);
            imagesc(app.rawdataplot,t,f,10*log10(S)');
            set(app.rawdataplot,'YDir', 'normal');
            axis(app.rawdataplot,[0 inf 0 30])
            colormap(app.rawdataplot,jet)
            caxis(app.rawdataplot,[-30 20])
            colorbar(app.rawdataplot)
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            value = app.StartEditField.Value;
            taskstart1 = value;
            app.taskstart1 = taskstart1 * app.fs;
            disp('start time 1 entered')
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            value = app.EndEditField.Value;
            taskend1 = value;
            app.taskend1 = taskend1 * app.fs;
            disp('end time 1 entered')
        end

        % Value changed function: StartEditField_2
        function StartEditField_2ValueChanged(app, event)
            value = app.StartEditField_2.Value;
            taskstart2 = value;
            app.taskstart2 = taskstart2 * app.fs;
            disp('start time 2 entered')
        end

        % Value changed function: EndEditField_2
        function EndEditField_2ValueChanged(app, event)
            value = app.EndEditField_2.Value;
            taskend2 = value;
            app.taskend2 = taskend2 * app.fs;
            disp('end time 2 entered')
        end

        % Selection changed function: DataButtonGroup
        function DataButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataButtonGroup.SelectedObject;
            disp('load selected...')
            if strcmp(selectedButton.Text, 'time series')
                app.x = linspace(app.taskstart1,app.taskend1,length(app.rawdat(app.ch_plt,app.taskstart1:app.taskend1)));
                app.y = app.rawdat(app.ch_plt,app.taskstart1:app.taskend1);
                app.type = 'ts';
            elseif strcmp(selectedButton.Text, 'PSD')
                fxx = 1:.1:40;
                app.psd1 = pwelch(app.rawdat(app.ch_plt,app.taskstart1:app.taskend1), app.fs, app.fs/2, fxx, app.fs);
                app.x = fxx;
                app.y = mag2db(app.psd1);
                app.type = 'psd';
            end
            disp('done')
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            disp('plotting...')
            plot(app.sec1axis,app.x,app.y)
            if strcmp(app.type, 'psd')
                axis(app.sec1axis,"tight")
            end
            disp('done')
        end

        % Size changed function: PSDTab
        function PSDTabSizeChanged(app, event)
            position = app.PSDTab.Position;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin('base', 's', app);
        end

        % Value changed function: ChanneltoplotDropDown
        function ChanneltoplotDropDownValueChanged(app, event)
            value = app.ChanneltoplotDropDown.Value;
            app.ChanneltoplotDropDown.Items = app.chlist;
            app.ch_plt = find(strcmp(app.ChanneltoplotDropDown.Items,value));
        end

        % Selection changed function: DataDisplayButtonGroup_2
        function DataDisplayButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.DataDisplayButtonGroup_2.SelectedObject;
            disp('load selected...')
            if strcmp(selectedButton.Text, 'time series')
                app.x = linspace(app.taskstart2,app.taskend2,length(app.rawdat(app.ch_plt,app.taskstart2:app.taskend2)));
                app.y = app.rawdat(app.ch_plt,app.taskstart2:app.taskend2);
                app.type = 'ts';
            elseif strcmp(selectedButton.Text, 'PSD')
                fxx = 1:40;
                app.psd2 = pwelch(app.rawdat(app.ch_plt,app.taskstart2:app.taskend2), app.fs, app.fs/2, fxx, app.fs);
                app.x = fxx;
                app.y = mag2db(app.psd2);
                app.type = 'psd';
            end
            disp('done')
        end

        % Button pushed function: GOButton_2
        function GOButton_2Pushed(app, event)
            disp('plotting...')
            plot(app.sec2axis,app.x,app.y)
            %             if strcmp(app.type, 'psd')
            axis(app.sec1axis,"tight")
            %             end
            xlim([0 25])
            disp('done')
        end

        % Callback function
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value
                app.usePRED = 1;
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value
                app.useFREE = 1;
                filt = struct;
                filt.order = 2;
                filt.method = [];
                filt.highps = 0.5;
                filt.lowps = 4.5;
                filt.trough_dur = [0.5, 5];
                filt.whole_dur = [0.75, 10];
                filt.peak_thresh = [];
                filt.neg_max_amp = 10;
                filt.ptp_thresh = [];
                filt.ptp_min = 10;
                filt.slope = 1;
                app.filt = filt;
            end
        end

        % Selection changed function: UsepredefinedsettingsButtonGroup_2
        function UsepredefinedsettingsButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.UsepredefinedsettingsButtonGroup_2.SelectedObject;
            filt = struct;
            filt.order = 2;
            filt.method = [];
            filt.highps = [];
            filt.lowps = [];
            filt.trough_dur = [];
            filt.whole_dur = [];
            filt.peak_thresh = [];
            filt.neg_max_amp = [];
            filt.ptp_thresh = [];
            filt.ptp_min = [];
            switch selectedButton.Text
                case 'Massimini2004'
                    filt.order = 2;
                    filt.method = 'butter';
                    filt.highps = 0.1;
                    filt.lowps = 4;
                    filt.trough_dur = [0.3, 1];
                    filt.neg_max_amp = -80;
                    filt.ptp_min = 140;%
                case 'AASM'
                    filt.order = 2;
                    filt.method = 'butter';
                    filt.highps = 0.1;
                    filt.lowps = 4;
                    filt.trough_dur = [0.25, 1];
                    filt.neg_max_amp = -40;
                    filt.ptp_min = 75;
                case 'Ngo2015'
                    filt.order = 2;
                    filt.method = 'butter';
                    filt.highps = [];
                    filt.lowps = 3.5;
                    filt.trough_dur = [];
                    filt.whole_dur = [0.833, 2];
                    filt.peak_thresh = 1.25;
                    filt.neg_max_amp = [];
                    filt.ptp_thresh = 1.25;
                    filt.ptp_min = [];
                case 'Staresina2015'
                    filt.order = 3;
                    filt.method = 'butter';
                    filt.highps = [];
                    filt.lowps = 1.25;
                    filt.whole_dur = [0.8, 2];
                case 'Fattinger2017'
                    filt.order = 2; % assumed, no info in the paper
                    filt.method = 'cheby2';
                    filt.highps = 5;
                    filt.lowps = 9;
                    filt.highstop = 4;
                    filt.lowstop = 12;
                    filt.whole_dur = [0, 5];
                    filt.trough_dur = [0.06, 0.083];
                    filt.ptp_thresh = 2;
                case 'Hung2013'
                    filt.order = 1;
                    filt.method = 'kaiser';
                    filt.highps = 2.5;
                    filt.lowps = 10;
                    filt.whole_dur = [0.1, 5];
            end
            app.filt = filt;
            app.filttype = selectedButton.Text;
        end

        % Button pushed function: RunBlinkDetectionButton
        function RunBlinkDetectionButtonPushed(app, event)
            Eyes_chn = app.Eyechn; % find peaks in Fp1, Fp2, E1 and E2
            eotime = (app.taskstart1):(app.taskend1);
            ts = app.rawdat;
            ts = ts(:,eotime);
            Eye_proc = struct;
            lowPassIIR = designfilt('lowpassiir', 'FilterOrder', 2, 'PassbandFrequency', 10, 'SampleRate', app.fs);
            [a b] = tf(lowPassIIR);
            
            for i = 1:length(Eyes_chn)
                Eye_orig = ts(Eyes_chn(i),:);
                Eye_HighPass = filter(a, b, double(Eye_orig));
                if abs(min(Eye_HighPass)) > abs(max(Eye_HighPass)) % check if blinks would show up as negative deflection, when electrode is closer to retina?
                    Eye_proc.data{i} = -Eye_HighPass;
                else
                    Eye_proc.data{i} = Eye_HighPass;
                end
                pk=[];lc=[];
                [pk, lc] = findpeaks(detrend(Eye_proc.data{i}),'MinPeakProminence',std(detrend(Eye_proc.data{i}))*app.BlinkThresh,'MinPeakDistance',.25*app.fs);
                BlinkTimes = zeros(size(Eye_proc.data{i}'));
                BlinkTimes(find(any(abs((eotime-eotime(1) - lc')) < (.25*app.fs))))=1;
                Eye_proc.BlinkTimes{i} = BlinkTimes;
            end
            
            % if blinks exist in 3 out of 4 channels, blinkID == true
            switch length(Eyes_chn)
                case 4
                    for k = 1:length(Eye_proc.BlinkTimes{1})
                        if Eye_proc.BlinkTimes{1}(k)+Eye_proc.BlinkTimes{2}(k)+Eye_proc.BlinkTimes{3}(k)+Eye_proc.BlinkTimes{4}(k) < 2
                            Blinksum(k) = 0;
                        else
                            Blinksum(k) = 1;
                        end
                    end
                case 3
                    for k = 1:length(Eye_proc.BlinkTimes{1})
                        if Eye_proc.BlinkTimes{1}(k)+Eye_proc.BlinkTimes{2}(k)+Eye_proc.BlinkTimes{3}(k) <=1
                            Blinksum(k) = 0;
                        else
                            Blinksum(k) = 1;
                        end
                    end
                case 2
                    for k = 1:length(Eye_proc.BlinkTimes{1})
                        if Eye_proc.BlinkTimes{1}(k)+Eye_proc.BlinkTimes{2}(k) == 0
                            Blinksum(k) = 0;
                        else
                            Blinksum(k) = 1;
                        end
                    end
                case 1
                    Blinksum = Eye_proc.BlinkTimes{1};
                otherwise
                    disp('You must select eye channels!')
            end
            assignin('base', 's', app);
            Eye_proc.BlinkTimes_fin = Blinksum;
            app.Eye_proc = Eye_proc;
            cla(app.BlinkDisplay,'reset');
            plot(app.BlinkDisplay,Eye_proc.BlinkTimes_fin*200)
            hold(app.BlinkDisplay,'on')
            plot(app.BlinkDisplay,Eye_proc.data{1})
            hold(app.BlinkDisplay,'off')
        end

        % Value changed function: E1CheckBox
        function E1CheckBoxValueChanged(app, event)
            value = app.E1CheckBox.Value;
            if value
                if ~any(app.Eyechn(:) == 27) && length(app.chlist)>32
                    app.Eyechn = [app.Eyechn 27];
                else 
                    app.Eyechn = [app.Eyechn 24];
                end
            end
        end

        % Value changed function: E2CheckBox
        function E2CheckBoxValueChanged(app, event)
            value = app.E2CheckBox.Value;
            if value
                if ~any(app.Eyechn(:) == 28) && length(app.chlist)>32
                    app.Eyechn = [app.Eyechn 28];
                else
                   app.Eyechn = [app.Eyechn 25];
                end
            end
        end

        % Value changed function: Fp1CheckBox
        function Fp1CheckBoxValueChanged(app, event)
            value = app.Fp1CheckBox.Value;
            if value
                if ~any(app.Eyechn(:) == 1)
                    app.Eyechn = [app.Eyechn 1];
                end
            end
        end

        % Value changed function: Fp2CheckBox
        function Fp2CheckBoxValueChanged(app, event)
            value = app.Fp2CheckBox.Value;
            if value
                if ~any(app.Eyechn(:) == 2)
                    app.Eyechn = [app.Eyechn 2];
                end
            end
        end

        % Callback function
        function ResetButtonPushed(app, event)
            app.Eyechn = [];
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.BlinkThresh = value;
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            if value
                app.usePRED = 1;
            end
        end

        % Value changed function: halfwavedurationEditField_3, 
        % ...and 1 other component
        function halfwavedurationEditFieldValueChanged(app, event)
            lowval = app.halfwavedurationsEditField.Value;
            highval = app.halfwavedurationEditField_3.Value;
            app.filt.trough_dur = [lowval, highval];
        end

        % Button pushed function: RunSWdetectionButton
        function RunSWdetectionButtonPushed(app, event)
            if app.usePRED
                disp('Filtering started')
                if strcmp(app.filttype,'Massimini2004')
                    chn = [3:26,29:60];%app.run_detect_ch;
                    ts = app.rawdat(chn,(app.taskstart1:app.taskend1));
                    if size(ts,2) > size(ts,1)
                        ts_resh = detrend(double(ts'));
                    else
                        ts_resh = detrend(double(ts));
                    end
                    filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highps/10, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','butter','MatchExactly', 'passband');
                    seg_filtered = filtfilt(filter_SW,ts_resh);
                    app.filt_dat = seg_filtered;
                elseif strcmp(app.filttype,'AASM')
                    chn = [3:26,29:60];
                    ts = app.rawdat(chn,(app.taskstart1:app.taskend1));
                    if size(ts,2) > size(ts,1)
                        ts_resh = detrend(double(ts'));
                    else
                        ts_resh = detrend(double(ts));
                    end
                    filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highps/10, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','butter','MatchExactly', 'passband');
                    seg_filtered = filtfilt(filter_SW,ts_resh);
                    app.filt_dat = seg_filtered;
                elseif strcmp(app.filttype,'Ngo2015')
                    chn = [3:26,29:60];
                    ts = app.rawdat(chn,(app.taskstart1:app.taskend1));
                    if size(ts,2) > size(ts,1)
                        ts_resh = detrend(double(ts'));
                    else
                        ts_resh = detrend(double(ts));
                    end
                    filter_SW = designfilt('lowpassiir', 'PassbandFrequency', app.filt.lowps, 'StopbandFrequency', app.filt.lowps*2, 'PassbandRipple', .1, 'StopbandAttenuation', 1, 'SampleRate', app.fs, 'DesignMethod', 'butter', 'MatchExactly', 'passband');
                    seg_filtered = filtfilt(filter_SW,ts_resh);
                    app.filt_dat = seg_filtered;
                    app.filttype = 'zx';
                elseif strcmp(app.filttype,'Staresina2015')
                    chn = [3:26,29:60];
                    ts = app.rawdat(chn,(app.taskstart1:app.taskend1));
                    if size(ts,2) > size(ts,1)
                        ts_resh = detrend(double(ts'));
                    else
                        ts_resh = detrend(double(ts));
                    end
                    filter_SW = designfilt('lowpassiir', 'FilterOrder', app.filt.order, 'HalfPowerFrequency', app.filt.lowps, 'SampleRate', app.fs, 'DesignMethod', 'butter');
                    seg_filtered = filtfilt(filter_SW,ts_resh);
                    app.filt_dat = seg_filtered;
                    app.filttype = 'zx';
                elseif strcmp(app.filttype,'Fattinger2017')
                    chn = [3:26,29:60];
                    ts = app.rawdat(chn,(app.taskstart1:app.taskend1));
                    if size(ts,2) > size(ts,1)
                        ts_resh = detrend(double(ts'));
                    else
                        ts_resh = detrend(double(ts));
                    end
                    filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highstop, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowstop,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','cheby2','MatchExactly', 'passband');
                    seg_filtered = filtfilt(filter_SW,ts_resh);
                    app.filt_dat = seg_filtered;
                    app.filttype = 'zx';
                elseif strcmp(app.filttype,'Hung2013')
                    chn = [3:26,29:60];
                    ts = app.rawdat(chn,(app.taskstart1:app.taskend1));
                    if size(ts,2) > size(ts,1)
                        ts_resh = detrend(double(ts'));
                    else
                        ts_resh = detrend(double(ts));
                    end
                    filter_SW = designfilt('bandpassfir', 'StopbandFrequency1', app.filt.highps/10, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2, 'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'SampleRate', app.fs, 'DesignMethod', 'kaiserwin');
                    seg_filtered = filtfilt(filter_SW,ts_resh);
                    app.filt_dat = seg_filtered;
                    app.filttype = 'zx';
                end
            elseif app.useFREE
                chn = [3:26,29:59];%[1:23];
                ts = app.rawdat(chn,(app.taskstart1:app.taskend1));
                if size(ts,2) > size(ts,1)
                    ts_resh = detrend(double(ts'));
                else
                    ts_resh = detrend(double(ts));
                end
                if app.filt_method == 1 %butterworth
                    filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highps/10, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','butter','MatchExactly', 'passband');
                elseif app.filt_method == 2 % cheby 1
                    filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highps/5, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','cheby1','MatchExactly', 'passband');
                elseif app.filt_method == 3 % cheby 2
                    filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highps/5, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','cheby2','MatchExactly', 'passband');
                else % kaiser
                    filter_SW = designfilt('bandpassfir', 'StopbandFrequency1', app.filt.highps/10, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2, 'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'SampleRate', app.fs, 'DesignMethod', 'kaiserwin');
                end
                seg_filtered = filtfilt(filter_SW,ts_resh);
                app.filt_dat = seg_filtered;
                app.filttype = 'zx';
            else
                error 'have to choose a method'
            end
            fs = app.fs; filt = app.filt;
            
            app.filt_dat(1:20,:) = min(app.filt_dat(21:end,:)) + (max(app.filt_dat(21:end,:))-min(app.filt_dat(21:end,:))).*rand(20,1); % eliminate initial edge effect from filter
            app.filt_dat(end-19:end,:) = min(app.filt_dat(21:end-19,:)) + (max(app.filt_dat(21:end-19,:))-min(app.filt_dat(21:end-19,:))).*rand(20,1); % eliminate ending edge effect from filter
            if app.useFREE == 1
                app.filt_dat = app.reref; % do the re-reference
            end
            % find where time series above zero
            disp('Finding half waves....')
            if app.usePRED
                for k = 1:length(chn)
                    clearvars troughs peak_troughs
                    events =[];
                    if size(app.filt_dat,2) > size(app.filt_dat,1)
                        app.filt_dat = app.filt_dat';
                    else
                        app.filt_dat = app.filt_dat;
                    end
                    times = 0:1/fs:size(app.filt_dat(:,k),1)*(1/fs);
                    if strcmp(app.filttype,'Massimini2004') || strcmp(app.filttype,'AASM')
                        above_data_single = app.filt_dat(:,k) >=0;
                        new_ts_filt = [0 above_data_single(1:end-1)' 0]; % maybe add try catch to resolve the dimension
                        cross_thresh = diff(new_ts_filt);
                        event_start = find(cross_thresh == 1);
                        event_end = find(cross_thresh == -1);
                        % events -
                        %           N x 2 matrix with starting and ending times
                        events = [event_start ;event_end];% stack the start and end time of positive phase together
                    elseif strcmp(app.filttype,'zx')
                        zx = find(diff(sign(app.filt_dat(:,k))) < 0); % find all pos-to-neg zero-crossings
                        interval = diff(zx)/fs;
                        dur = filt.whole_dur;
                        idx_starts = find(interval>=dur(1) & interval<dur(2));
                        idx_ends = idx_starts + 1;
                        if ~isempty(idx_ends)
                            events = [zx(idx_starts)' ;zx(idx_ends)'];
                        end
                    else
                        disp('No half waves selected!')
                    end
                    
                    if ~isempty(events) && ~isempty(filt.trough_dur)       % if evnets not empty
                        % now check if detected halfwaves are within desired range
                        disp('Now checking the trough duration...')
                        max_dur = ones(length(events),1); min_dur = max_dur;
                        times = 0:1/fs:length(app.filt_dat(:,k))*(1/fs);
                        limits = app.filt.trough_dur; % duration limits in seconds
                        min_dur = times(events(2,:)-1) - times(events(1,:)) >= limits(1);
                        max_dur = times(events(2,:)-1) - times(events(1,:)) <= limits(2);
                        troughs = events(:,find(min_dur & max_dur)); % trough start and end time stamp that meets critarion, in this case,
                        % half wave(>=0) is smaller than 2 but larger than 0.8
                    end
                    
                    % if using the neg-to-pos detection method
                    if ~isempty(events) && strcmp(app.filttype,'zx')
                        new_events = zeros(size(events,2),3);
                        events = [events(1,:); new_events'; events(2,:)];
                        for ik = 1:size(events,2)
                            ev_dat = app.filt_dat(events(1,ik):events(5,ik),k);
                            [pk,lc] = min(ev_dat);
                            events(2,ik) = events(1,ik) + lc; %trough
                            events(3,ik) = events(1,ik) + find(diff(sign(ev_dat)) > 0);% -to+ in this segment
                            [pk,lc] = max(ev_dat);
                            events(4,ik) = events(1,ik) + lc; %peak
                        end
                        
                        if ~isempty(filt.peak_thresh) && ~isempty(events)
                            % check the neg peak thresh
                            idx_trough = events(2,:);
                            % trough threshhold is set as peak_thresh (float) times the mean trough amplitude over all events
                            neg_peak_thresh = mean(app.filt_dat(idx_trough,k)) * filt.peak_thresh;
                            events = events(:,app.filt_dat(idx_trough) < neg_peak_thresh);
                        end
                        
                        if ~isempty(filt.ptp_thresh) && ~isempty(events)
                            ptp = app.filt_dat(events(4,:),k) - app.filt_dat(events(2,:),k); % peak to peak amplitude threshold
                            if filt.ptp_thresh >50
                                ptp_thresh = prctile(ptp, filt.ptp_thresh);
                            else
                                ptp_thresh = mean(ptp) * filt.ptp_thresh;
                            end
                            events = events(:, ptp > ptp_thresh);
                        end
                    end
                    
                    % if we care about the peak amplitude, we nneed to check if data in troughs
                    % satisfies amplitude limit
                    if ~isempty(filt.neg_max_amp) && ~isempty(troughs)
                        disp('Check max amplitude...')
                        amp_limit = abs(filt.neg_max_amp);
                        peak_troughs =  false(1, max(size(troughs)));
                        for ii=1:size(troughs,2)
                            if any(app.filt_dat((troughs(1,ii):troughs(2,ii)),k)>= amp_limit)
                                peak_troughs(1,ii) = true;
                            end
                        end
                        if sum(peak_troughs) ~= 0
                            troughs = troughs(:,peak_troughs);
                            events = troughs; % update events info
                        else
                            disp('No half wave reach the amplitude threshold!')
                            events = [];
                        end
                        %                 else
                        %                     disp('No half wave in range!')
                        %                     events = [];
                    end
                    
                    if ~isempty(filt.ptp_min) && ~isempty(events)
                        disp('Check ptp...')
                        % check if there is max full wave duration?
                        % if no max full wave duration, default to 5 seconds
                        max_full_dur = 5;
                        min_ptp = filt.ptp_min;
                        window = fs * max_full_dur;
                        peak_and_end = zeros(size(events,2),3);
                        events = [events; peak_and_end'];
                        selected = [];
                        for i=1:size(events,2)
                            if (events(1,i)+window) <= max(size(app.filt_dat))
                                zero_crossings = find(diff(sign(app.filt_dat(events(2,i):(events(1,i)+window),k))));
                            else
                                zero_crossings = find(diff(sign(app.filt_dat(events(2,i):end,k))));
                            end
                            if ~isempty(zero_crossings) && (events(2,i) + zero_crossings(1) + 1) < max(size(app.filt_dat))
                                events(3,i) = events(2,i) + zero_crossings(1) + 1;
                                [pk, lc] = max(app.filt_dat(events(1,i):events(2,i),k));
                                events(4,i)=events(1,i)+lc; % peak
                            else
                                disp('No zero-crossing within max full wave duration')
                                continue;
                            end
                            [pk,lc] = min(app.filt_dat(events(2,i):events(3,i),k));
                            events(5,i) = events(2,i) + lc - 1;% trough
                            if abs(app.filt_dat(events(4,i),k)-app.filt_dat(events(5,i),k)) < min_ptp
                                disp('ptp too low, reject this slow wave')
                            else
                                selected = [selected, i];
                            end
                        end
                        if ~isempty(selected)
                            events = events(:,selected);
                        else
                            events = [];
                        end
                    end
                    
                    % sanity check
                    if ~isempty(events)
                        disp('Sanity check...')
                        if strcmp(app.filttype,'zx')
                            dur=(events(5,:)-events(1,:))/fs ;
                            continuous = times(events(5,:))-times(events(1,:))-dur < 0.1 ; % 0.1 is the default tolerance
                            events = events(:,continuous);
                        else
                            dur=(events(2,:)-events(1,:))/fs ;
                            continuous = times(events(2,:))-times(events(1,:))-dur < 0.1 ; % 0.1 is the default tolerance
                            events = events(:,continuous);
                        end
                    end
                    
                    if ~isempty(events)
                        disp('Save slow waves now...')
                        % store slow wave info
                        for i = 1:size(events,2)
                            if ~strcmp(app.filttype,'zx')
                                if i == 1
                                    slow_waves = struct('start', {times(events(1,i))}, 'trough_time', {times(events(5,i))},...
                                        'zero_time',{times(events(3,i))},'peak_time',{times(events(4,i))},'end',{times(events(2,i)-1)},...
                                        'trough_val',{app.filt_dat(events(5,i),k)},'peak_val',{app.filt_dat(events(4,i),k)},...
                                        'full_sw_dur',{(events(3,i)-events(1,i))/fs},'P2P',{abs(app.filt_dat(events(4,i),k)-app.filt_dat(events(5,i),k))});
                                else
                                    slow_waves(end+1) = struct('start', {times(events(1,i))}, 'trough_time', {times(events(5,i))},...
                                        'zero_time',{times(events(3,i))},'peak_time',{times(events(4,i))},'end',{times(events(2,i)-1)},...
                                        'trough_val',{app.filt_dat(events(5,i),k)},'peak_val',{app.filt_dat(events(4,i),k)},...
                                        'full_sw_dur',{(events(3,i)-events(1,i))/fs},'P2P',{abs(app.filt_dat(events(4,i),k)-app.filt_dat(events(5,i),k))});
                                end
                            else
                                if i == 1
                                    slow_waves = struct('start', {times(events(1,i))}, 'trough_time', {times(events(2,i))},...
                                        'zero_time',{times(events(3,i))},'peak_time',{times(events(4,i))},'end',{times(events(5,i)-1)},...
                                        'trough_val',{app.filt_dat(events(2,i),k)},'peak_val',{app.filt_dat(events(4,i),k)},...
                                        'full_sw_dur',{(events(5,i)-events(1,i))/fs},'P2P',{abs(app.filt_dat(events(4,i),k)-app.filt_dat(events(2,i),k))});
                                else
                                    slow_waves(end+1) = struct('start', {times(events(1,i))}, 'trough_time', {times(events(2,i))},...
                                        'zero_time',{times(events(3,i))},'peak_time',{times(events(4,i))},'end',{times(events(5,i)-1)},...
                                        'trough_val',{app.filt_dat(events(2,i),k)},'peak_val',{app.filt_dat(events(4,i),k)},...
                                        'full_sw_dur',{(events(5,i)-events(1,i))/fs},'P2P',{abs(app.filt_dat(events(4,i),k)-app.filt_dat(events(2,i),k))});
                                end
                            end
                        end
                        
                        % after detecting the slow waves, we will check if the half-wave overlaps
                        % with detected blinks
                        % compare the duration of the waves
                        % compare the start and end of the waves
                        change = find(diff(sign(app.Eye_proc.BlinkTimes_fin)));
                        blink_start = change(1:2:end)/fs;
                        for i=1:length(slow_waves)
                            if any(abs(slow_waves(i).zero_time - blink_start) < .5)
                                slow_waves(i).overlap_blink = 1;
                            else
                                slow_waves(i).overlap_blink = 0;
                            end
                        end
                        
                        % count false positive rate
                        app.FP_rate{k} = sum([slow_waves.overlap_blink])/length(slow_waves) * 100;
                        app.slow_waves{k} = slow_waves;
                    else
                        disp('No slow waves detected!')
                    end
                end
            else % using free params
                n_canonical = size(app.cano_dat,1); % number of canonical waves
                disp('Loading user defined params...')
                SWcnt = 1;
                % for MNP method
                slow_waves_MNP = struct('start', [], 'trough_time', [],...
                    'zero_time',[],'peak_time',[],'end',[],...
                    'trough_val',[],'peak_val',[],...
                    'full_sw_dur',[],'P2P',[],'Region',[],'DownIx',[],'PeakIx',[],'UpIx',[],'PeakAmp',[],...
                    'Negslope',[],'Posslope',[],'overlap_blink',[]);
                disp('Finished loading')

                for ref_wave = 1:n_canonical
                    slopeData   = [0 diff(app.cano_dat(ref_wave, :))];
                    
                    % find all the negative peaks
                    % when slope goes from negative to a positive
                    MNP  = find(diff(sign(slopeData)) == 2);
                    % Find all the positive peaks
                    MPP  = find(diff(sign(slopeData)) == -2);
                    
                    % Check for earlier MPP than MNP
                    if MNP(1) < MPP(1)
                        MNP(1) = [];
                    end
                    % Check that last MNP has a later MPP
                    if MNP(end) > MPP(end)
                        MNP(end)=[];
                    end
                    
                    nb = 1;
                    while nb > 0;
                        posBumps = MPP(2 : end) - MNP < ...
                            .5 * app.fs / 10;
                        MPP([false, posBumps]) = [];
                        MNP(posBumps) = [];
                        
                        negBumps = MNP - MPP(1 : end - 1) < ...
                            .5 * app.fs / 10;
                        MPP(negBumps) = [];
                        MNP(negBumps) = [];
                        
                        nb = max(sum(posBumps), sum(negBumps));
                    end
                    
                    devian_dat = zeros(n_canonical, 1);
                    devian_dat(ref_wave) = mad(app.cano_dat(ref_wave, MNP), 1);% returns mean absolute deviation
                    
                    % calculate amplitude threshold criteria
                    % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                    % absolute deviation from the median (to avoid outliers)
                    rel_amp_cutoff = 3;
                    abs_amp_cutoff(ref_wave) = (devian_dat(ref_wave) * rel_amp_cutoff) + abs(median(app.cano_dat(ref_wave, MNP)));
                    
                    % Define badWaves
                    badWaves = false(1, length(MNP));
                    
                    % Wavelength criteria
                    % ```````````````````
                    % MPP->MPP length
                    % trough/negative half wave duration
                    MPP2MPPlength = diff(MPP);
                    badWaves    ( MPP2MPPlength < app.filt.trough_dur(1)*app.fs...
                        | MPP2MPPlength > app.filt.trough_dur(2)*app.fs)...
                        = true;
                    fprintf('Percent Bad Waves: %0.8f %% \n',sum(badWaves)/length(badWaves)*100)

                    % MNP->MPP length
                    % must be at least half of the specified wavelength
                    MNP2MPPlength = MPP(2:end)-MNP;
                    if ~isempty(app.filt.whole_dur)
                        badWaves    ( MNP2MPPlength < app.filt.whole_dur(1)*app.fs/2 ...
                            | MNP2MPPlength > app.filt.whole_dur(2)*app.fs/2) ...
                            = true;
                    else
                        badWaves    ( MNP2MPPlength < app.filt.trough_dur(1)*app.fs ...
                            | MNP2MPPlength > app.filt.trough_dur(2)*app.fs) ...
                            = true;
                    end
                    
                    
                    % Amplitude criteria
                    % ```````````````````
                    % mark lower than threshold amps and larger than 220uV (artifacts)
                    badWaves    ( app.cano_dat(ref_wave, MNP) > -abs_amp_cutoff(ref_wave)...
                        | app.cano_dat(ref_wave, MNP) < -220) = true;
                    
                    % peak to peak amplitude
                    p2p = app.cano_dat(ref_wave, MPP(2:end)) - app.cano_dat(ref_wave, MNP);
                    % peaks should not be calculated for envelope references
                    if ~isempty(app.filt.ptp_min)
                        badWaves ( p2p < app.filt.ptp_min)= true;
                    end
                    
                    if isempty(find(~badWaves))
                        disp('No SW meets the criterion, move on...')
                        slow_waves_MNP(SWcnt).Region = ref_wave; SWcnt = SWcnt + 1;
                    else 
                        for n = find(~badWaves)
                            slow_waves_MNP(SWcnt).Region = [ref_wave];
                            slow_waves_MNP(SWcnt).DownIx = MPP(n);
                            slow_waves_MNP(SWcnt).PeakIx = MNP(n);
                            slow_waves_MNP(SWcnt).UpIx = MPP(n+1);
                            slow_waves_MNP(SWcnt).PeakAmp = app.cano_dat(ref_wave, MNP(n));
                            slow_waves_MNP(SWcnt).P2P = p2p(n);
                            slow_waves_MNP(SWcnt).Negslope = min(slopeData(1,MPP(n):MPP(n+1)));
                            slow_waves_MNP(SWcnt).Posslope = max(slopeData(1,MPP(n):MPP(n+1)));
                            SWcnt = SWcnt + 1;
                        end
                    end
                    
                end
                % sort the waves in temporal order
                [~, sorted_index] = sort([slow_waves_MNP.DownIx]);
                slow_waves_MNP = slow_waves_MNP(sorted_index);
                
                % find neighbouring channels for all eeg channels, except
                % eye channels
                load('ChannelLocs.mat'); % no E1, E2 locations
                Nearchn = findNeighbours(locs);
                
                % calculate the window size in samples around center point
                winsize = 0.5;
                win = round(winsize * app.fs);
                
                % Correlation Method
                ToDelete = []; thrs = .7; % maybe consider changing this to .7 ?
                sample=[];
                % check that the search window doesn't cross data start or end
                for n = 1:length(slow_waves_MNP)
                    if isempty(slow_waves_MNP(n).PeakIx) || slow_waves_MNP(n).PeakIx - win * 2 < 1 || slow_waves_MNP(n).PeakIx + win * 2 > size(app.reref,2)
                        ToDelete(end+1)=n;
                        continue
                    end
                    % get sample channel data around the slow wave peak
                    sample   = app.reref(:,slow_waves_MNP(n).PeakIx - win * 2 : slow_waves_MNP(n).PeakIx + win * 2);
                    cano_ref = mean(app.cano_dat(slow_waves_MNP(n).Region, slow_waves_MNP(n).PeakIx - win ...
                        : slow_waves_MNP(n).PeakIx + win), 1);
                    
                    % cross correlate with the reference channel
                    cc = zeros(size(sample,1), win*2+1);
                    for t = 1:win*2+1
                        cc(:,t) = corr(cano_ref',sample(:,t:t+size(cano_ref,2)-1)');
                    end
                    % find the maximum correlation and location
                    [maxCC, maxID]      = max(cc,[],2);
                    
                    % cross correlation plot
                    if n == 1 % plot the first slow wave for sanity check
                        [~, sort_ind] = sort(maxID, 1, 'ascend');
                        image_data = cc(sort_ind, :);
                        time_delay = ([-win:win] / app.fs) * 1000;
                        figure('color', 'w'); axes('nextPlot', 'add', 'yDir', 'reverse');
                        contourf(time_delay, 1:size(image_data, 1),...
                            image_data, 15, ...
                            'linestyle', 'none');
                    end
                    % channels with correlation above threshold
                    cc_chn = false(size(app.reref,1),1);
                    cc_chn(maxCC > thrs) = true;
                    
                    % if no channels correlate well with the reference then delete this sw
                    if sum(cc_chn)==0
                        ToDelete(end+1)=n;
                        continue
                    end
                    
                    % Minimum amplitude threshold (10% of maximum)
                    NegAmp = nan(size(app.reref,1), 1);
                    NegAmp(cc_chn,:) = min(sample(cc_chn,:),[],2);
                    cc_chn(NegAmp > mean(app.filt.neg_max_amp)/10) = false;
                    
%                     % Test for potential clusters
%                     clusters = if_cluster(cc_chn, Nearchn(c));
%                     
%                     % after checking cluster
%                     clusters(isnan(clusters)) = 0;
%                     nclusters = unique(clusters);
%                     
%                     if length(nclusters) > 2
%                         maxCluster = 0;
%                         for i = 2:length(nclusters)
%                             sCluster = sum(clusters == nclusters(i));
%                             if sCluster > maxCluster
%                                 maxCluster = sCluster;
%                                 keepCluster = i;
%                             end
%                         end
%                         cc_chn = clusters == nclusters(keepCluster);
%                     end
                    
                    % eliminate the potentially false channels from the cluster test
                    % in the negative amplitudes variable
                    NegAmp(~cc_chn) = nan;
                    
                    % Delay Calculation
                    if sum(cc_chn) ~= 0
                        slow_waves_MNP(n).Travelling_Delays_corr = [];
                        slow_waves_MNP(n).Travelling_Delays_corr = nan(size(app.reref,1),1);
                        slow_waves_MNP(n).Travelling_Delays_corr(cc_chn)...
                            = maxID(cc_chn) - min(maxID(cc_chn));
                        
                        slow_waves_MNP(n).Channels_Globality_corr = [];
                        slow_waves_MNP(n).Channels_Globality_corr = sum(cc_chn)/length(cc_chn)*100;
                        slow_waves_MNP(n).Channels_Active_corr 	= [];
                        slow_waves_MNP(n).Channels_Active_corr 	= cc_chn;
                    end
                end
                
                % Threshold Method
                ToDelete = []; thrs = 1;sample=[];
                % check that the search window doesn't cross data start or end
                for n = 1:length(slow_waves_MNP)
                    if isempty(slow_waves_MNP(n).PeakIx) || slow_waves_MNP(n).PeakIx - win < 1 || slow_waves_MNP(n).PeakIx + win * 3 > size(app.reref,2)
                        ToDelete(end+1)=n;
                        continue
                    end
                    % get sample channel data around the slow wave peak
                    sample   = app.reref(:,slow_waves_MNP(n).PeakIx - win : slow_waves_MNP(n).PeakIx + win);
                    
                    % min amplitude thresh for channels
                    [NegAmp, minChId] = min(sample,[],2);
                    slow_waves_MNP(n).Channels_Active_thr 	= [];
                    slow_waves_MNP(n).Channels_Active_thr  = NegAmp < -mean(app.filt.neg_max_amp) * thrs;
                    
                    % for peak to peak check we need a longer window after the peak
                    sample   = app.reref(:,slow_waves_MNP(n).PeakIx - win : slow_waves_MNP(n).PeakIx + win*3);
                    
                    % pre-allocate the positive amplitudes
                    posPeakAmp = nan(size(sample, 1), 1);
                    % find the positive peak after the negative peak
                    for nCh = find(slow_waves_MNP(n).Channels_Active_thr == 1)'
                        posPeakAmp(nCh) = max(sample(nCh, minChId(nCh):end));
                        poslc(nCh) = find(app.reref(nCh,:) == posPeakAmp(nCh));
                    end
                    slow_waves_MNP(n).Channels_Active_thr(posPeakAmp - NegAmp < ...
                        app.filt.ptp_min * thrs) = false;
                    
                    % slope check
                    for nCh = find(slow_waves_MNP(n).Channels_Active_thr == 1)'
                        pos2negslope(nCh) = (poslc(nCh) - minChId(nCh)) / app.fs;
                    end
                    slow_waves_MNP(n).Channels_Active_thr(pos2negslope < app.filt.slope) = false;
                    
                    % if no channels correlate well with the reference then delete this sw
                    if sum(slow_waves_MNP(n).Channels_Active_thr)==0
                        ToDelete(end+1)=n;
                        continue
                    end
                    
                    % Delay Calculation
                    slow_waves_MNP(n).Travelling_Delays_thr = [];
                    slow_waves_MNP(n).Travelling_Delays_thr = nan(size(app.reref,1),1);
                    slow_waves_MNP(n).Travelling_Delays_thr(slow_waves_MNP(n).Channels_Active_thr)...
                        = minChId(slow_waves_MNP(n).Channels_Active_thr) - min(minChId(slow_waves_MNP(n).Channels_Active_thr));
                    slow_waves_MNP(n).Channels_Globality_thr = [];
                    slow_waves_MNP(n).Channels_Globality_thr = sum(slow_waves_MNP(n).Channels_Active_thr)/length(slow_waves_MNP(n).Channels_Active_thr)*100;
                    
                end

                
                %% for ZXing method
                SWcnt = 1;
                slow_waves_ZX = struct('start', [], 'trough_time', [],...
                    'zero_time',[],'peak_time',[],'end',[],...
                    'trough_val',[],'peak_val',[],...
                    'full_sw_dur',[],'P2P',[],'Region',[],'DownIx',[],'PeakIx',[],'UpIx',[],'PeakAmp',[],...
                    'Negslope',[],'Posslope',[],'overlap_blink',[]);
                
                for ref_wave = 1:n_canonical
                    slopeData   = [0 diff(app.cano_dat(ref_wave, :))];
                    
                    % Get Downward and Upward Zero Crossings
                    signData    = sign(app.cano_dat(ref_wave,:));
                    % fix rare issue where slope is exactly 0
                    signData(signData == 0) = 1;
                    % -2 indicates when the sign goes from 1 to -1
                    P2NZX = find(diff(signData) < 0);
                    N2PZX = find(diff(signData) > 0);
                    
                    % make the negative to positive zxing the sample after the zero-crossing
                    N2PZX = N2PZX + 1;
                    
                    % Calculate xth percentile slope
                    x = sort(slopeData);
                    slopeThresh = prctile(x(x>0), .75 * 100);% .75 is Percentage cut-off for slopes
                    
                    % Check for earlier initial neg to pos zxing than pos to neg zxing
                    if P2NZX(1) >= N2PZX(1)
                        N2PZX(1)=[];
                        if length(P2NZX) ~= length(N2PZX)
                            P2NZX(end)=[];
                        end
                    end
                    
                    if length(P2NZX)>length(N2PZX)
                        P2NZX(end) = [];
                    end
                    
                    % Test half wave duration
                    SWLengths = N2PZX-P2NZX;
                    % Too short
                    BadZX = SWLengths < app.filt.trough_dur(1)*app.fs;
                    % Too long
                    BadZX(  SWLengths > app.filt.trough_dur(2)*app.fs) = true;
                    % Eliminate the indices
                    P2NZX(BadZX) = [];
                    N2PZX(BadZX) = [];
                    
                    % Loop through each zxing for criteria
                    for n = 1:length(P2NZX)
                        % Test for negative amplitude
                        [NegPeakAmp, NegPeakId] = min(app.cano_dat(ref_wave, P2NZX(n):N2PZX(n)));
                        if NegPeakAmp > -app.filt.neg_max_amp || ...
                                NegPeakAmp < -220
                            continue;
                        end
                        
                        NegPeakId = NegPeakId + P2NZX(n) - 1;
                        
                        % Test for peak to peak amplitude
                        sample_range = N2PZX(n):N2PZX(n) + 1 * app.fs;
                        sample_range(sample_range > size(app.cano_dat, 2)) = []; % adjust in case it hits the end sample
                        
                        % calculate the positive peak after the zero crossing
                        PosPeakAmp = max(app.cano_dat(ref_wave, sample_range));
                        if ~isempty(app.filt.ptp_min)
                            if PosPeakAmp - NegPeakAmp < app.filt.ptp_min
                                continue;
                            end
                        end
                        % Test for positive slope
                        MaxPosSlope = max(slopeData (1, P2NZX(n) : N2PZX(n)));
                        if MaxPosSlope < slopeThresh
                            continue;
                        end
                        
                        slow_waves_ZX(SWcnt).Region = [ref_wave];
                        slow_waves_ZX(SWcnt).DownIx = P2NZX(n);
                        slow_waves_ZX(SWcnt).PeakIx = NegPeakId;
                        slow_waves_ZX(SWcnt).UpIx = N2PZX(n);
                        slow_waves_ZX(SWcnt).PeakAmp = app.cano_dat(ref_wave, NegPeakId);
                        slow_waves_ZX(SWcnt).P2P = PosPeakAmp-NegPeakAmp;
                        slow_waves_ZX(SWcnt).Negslope = min(slopeData(1,P2NZX(n):N2PZX(n)));
                        slow_waves_ZX(SWcnt).Posslope = MaxPosSlope;
                        % Keep count of the waves found
                        SWcnt = SWcnt+1;
                        
                    end
                end
                % sort the waves in temporal order
                [~, sorted_index] = sort([slow_waves_ZX.DownIx]);
                slow_waves_ZX = slow_waves_ZX(sorted_index);
                
                % Correlation Method
                ToDelete = []; thrs = .7; % maybe consider changing this to .7 ?
                % check that the search window doesn't cross data start or end
                for n = 1:length(slow_waves_ZX)
                    if slow_waves_ZX(n).PeakIx - win * 2 < 1 || slow_waves_ZX(n).PeakIx + win * 2 > size(app.reref,2)
                        ToDelete(end+1)=n;
                        continue
                    end
                    % get sample channel data around the slow wave peak
                    sample   = app.reref(:,slow_waves_ZX(n).PeakIx - win * 2 : slow_waves_ZX(n).PeakIx + win * 2);
                    cano_ref = mean(app.cano_dat(slow_waves_ZX(n).Region, slow_waves_ZX(n).PeakIx - win ...
                        : slow_waves_ZX(n).PeakIx + win), 1);
                    
                    % cross correlate with the reference channel
                    cc = zeros(size(sample,1), win*2+1);
                    for t = 1:win*2+1
                        cc(:,t) = corr(cano_ref',sample(:,t:t+size(cano_ref,2)-1)');
                    end
                    % find the maximum correlation and location
                    [maxCC, maxID]      = max(cc,[],2);
                    
                    % cross correlation plot % for 1s before and 1s after
                    % slow wave trough
                    if n == 1 % plot the first slow wave for sanity check
                        [~, sort_ind] = sort(maxID, 1, 'ascend');
                        image_data = cc(sort_ind, :);
                        time_delay = ([-win:win] / app.fs) * 1000;
                        figure('color', 'w'); axes('nextPlot', 'add', 'yDir', 'reverse');
                        contourf(time_delay, 1:size(image_data, 1),...
                            image_data, 15, ...
                            'linestyle', 'none');
                    end
                    % channels with correlation above threshold
                    cc_chn = false(size(app.reref,1),1);
                    cc_chn(maxCC > thrs) = true;
                    
                    % if no channels correlate well with the reference then delete this sw
                    if sum(cc_chn)==0
                        ToDelete(end+1)=n;
                        continue
                    end
                    
                    % Minimum amplitude threshold (10% of maximum)
                    NegAmp = nan(size(app.reref,1), 1);
                    NegAmp(cc_chn,:) = min(sample(cc_chn,:),[],2);
                    cc_chn(NegAmp > mean(app.filt.neg_max_amp)/10) = false;
                    
%                     % Test for potential clusters
%                     clusters = if_cluster(cc_chn, Nearchn);
%                     
%                     % after checking cluster
%                     clusters(isnan(clusters)) = 0;
%                     nclusters = unique(clusters);
%                     
%                     if length(nclusters) > 2
%                         maxCluster = 0;
%                         for i = 2:length(nclusters)
%                             sCluster = sum(clusters == nclusters(i));
%                             if sCluster > maxCluster
%                                 maxCluster = sCluster;
%                                 keepCluster = i;
%                             end
%                         end
%                         cc_chn = clusters == nclusters(keepCluster);
%                     end
                    
                    % eliminate the potentially false channels from the cluster test
                    % in the negative amplitudes variable
                    NegAmp(~cc_chn) = nan;
                    
                    % Delay Calculation
                    if sum(cc_chn) ~= 0
                        slow_waves_ZX(n).Travelling_Delays_corr = [];
                        slow_waves_ZX(n).Travelling_Delays_corr = nan(size(app.reref,1),1);
                        slow_waves_ZX(n).Travelling_Delays_corr(cc_chn)...
                            = maxID(cc_chn) - min(maxID(cc_chn));
                        
                        slow_waves_ZX(n).Channels_Globality_corr = [];
                        slow_waves_ZX(n).Channels_Globality_corr = sum(cc_chn)/length(cc_chn)*100;
                        slow_waves_ZX(n).Channels_Active_corr 	= [];
                        slow_waves_ZX(n).Channels_Active_corr 	= cc_chn;
                    end
                end

                 % Threshold Method
                ToDelete = []; thrs = 1;sample=[];
                % check that the search window doesn't cross data start or end
                for n = 1:length(slow_waves_ZX)
                    if slow_waves_ZX(n).PeakIx - win < 1 || slow_waves_ZX(n).PeakIx + win * 3 > size(app.reref,2)
                        ToDelete(end+1)=n;
                        continue
                    end
                    % get sample channel data around the slow wave peak
                    sample   = app.reref(:,slow_waves_ZX(n).PeakIx - win : slow_waves_ZX(n).PeakIx + win);
                    
                    % min amplitude thresh for channels
                    [NegAmp, minChId] = min(sample,[],2);
                    slow_waves_ZX(n).Channels_Active_thr 	= [];
                    slow_waves_ZX(n).Channels_Active_thr  = NegAmp < -mean(app.filt.neg_max_amp) * thrs;
                    
                    % for peak to peak check we need a longer window after the peak
                    sample   = app.reref(:,slow_waves_ZX(n).PeakIx - win : slow_waves_ZX(n).PeakIx + win*3);
                    
                    % pre-allocate the positive amplitudes
                    posPeakAmp = nan(size(sample, 1), 1);
                    % find the positive peak after the negative peak
                    for nCh = find(slow_waves_ZX(n).Channels_Active_thr == 1)'
                        posPeakAmp(nCh) = max(sample(nCh, minChId(nCh):end));
                        poslc(nCh) = find(app.reref(nCh,:) == posPeakAmp(nCh));
                    end
                    slow_waves_ZX(n).Channels_Active_thr(posPeakAmp - NegAmp < ...
                        app.filt.ptp_min * thrs) = false;
                    
                    % slope check
                    for nCh = find(slow_waves_ZX(n).Channels_Active_thr == 1)'
                        pos2negslope(nCh) = (poslc(nCh) - minChId(nCh)) / app.fs;
                    end
                    slow_waves_ZX(n).Channels_Active_thr(pos2negslope < app.filt.slope) = false;
                    
                    % if no channels correlate well with the reference then delete this sw
                    if sum(slow_waves_ZX(n).Channels_Active_thr)==0
                        ToDelete(end+1)=n;
                        continue
                    end
                    
                    % Delay Calculation
                    slow_waves_ZX(n).Travelling_Delays_thr = [];
                    slow_waves_ZX(n).Travelling_Delays_thr = nan(size(app.reref,1),1);
                    slow_waves_ZX(n).Travelling_Delays_thr(slow_waves_ZX(n).Channels_Active_thr)...
                        = minChId(slow_waves_ZX(n).Channels_Active_thr) - min(minChId(slow_waves_ZX(n).Channels_Active_thr));
                    slow_waves_ZX(n).Channels_Globality_thr = [];
                    slow_waves_ZX(n).Channels_Globality_thr = sum(slow_waves_ZX(n).Channels_Active_thr)/length(slow_waves_ZX(n).Channels_Active_thr)*100;
                    assignin('base', 's', app);
                end

                
                % after detecting the slow waves, we will check if the half-wave overlaps
                % with detected blinks
                % compare the duration of the waves
                % compare the start and end of the waves
                if ~isempty(app.Eye_proc)
                change = find(diff(sign(app.Eye_proc.BlinkTimes_fin)));
                blink_start = change(1:2:end)/app.fs;
               
                for im=1:length(slow_waves_MNP)
                    if ~isempty([slow_waves_MNP(im).PeakIx]) % if any slow waves detected using MNP method
                        if any(abs(slow_waves_MNP(im).DownIx/app.fs - blink_start) < .5)
                            slow_waves_MNP(im).overlap_blink = 1;
                        else
                            slow_waves_MNP(im).overlap_blink = 0;
                        end
                    end
                end

                if ~isempty([slow_waves_ZX.PeakIx]) % if any slow waves detected using MNP method
                    for mi=1:length(slow_waves_ZX)
                        if any(abs(slow_waves_ZX(mi).DownIx/app.fs - blink_start) < .5)
                            slow_waves_ZX(mi).overlap_blink = 1;
                        else
                            slow_waves_ZX(mi).overlap_blink = 0;
                        end
                    end
                end
                end
                % count false positive rate
                app.FP_rate_MNP = sum([slow_waves_MNP.overlap_blink])/length(slow_waves_MNP) * 100;
                app.FP_rate_ZX = sum([slow_waves_ZX.overlap_blink])/length(slow_waves_ZX) * 100;
                app.slow_waves_MNP = slow_waves_MNP;
                app.slow_waves_ZX = slow_waves_ZX;
            end
        end

        % Button pushed function: ClearSWButton
        function ClearSWButtonPushed(app, event)
            app.slow_waves = [];
            app.slow_waves_ZX = [];
            app.slow_waves_MNP = [];
            app.FP_rate = [];
            app.FP_rate_ZX = [];
            app.FP_rate_MNP = [];
            app.usePRED = [];
            app.useFREE = [];
            app.Eyechn = [];
        end

        % Value changed function: filtermethodDropDown
        function filtermethodDropDownValueChanged(app, event)
            value = app.filtermethodDropDown.Value;
            app.filtermethodDropDown.Items = {'Butterworth', 'Cheby-1','Cheby-2','Kaiser'};
            app.filt_method = find(strcmp(app.filtermethodDropDown.Items,value)); % 1-butter, 2-cheby1, 3-cheby2, 4-kaiser
        end

        % Value changed function: lowpassfilterEditField_2
        function lowpassfilterEditField_2ValueChanged(app, event)
            value = app.lowpassfilterEditField_2.Value;
            app.filt.lowps = 4.5;
            if ~isempty(value)
                app.filt.lowps = [value];
            end
        end

        % Value changed function: highpassfilterEditField
        function highpassfilterEditFieldValueChanged(app, event)
            value = app.highpassfilterEditField.Value;
            app.filt.highps = 0.1;
            if ~isempty(value)
                app.filt.highps = [value];
            end
        end

        % Value changed function: peaktopeakminEditField
        function peaktopeakminEditFieldValueChanged(app, event)
            value = app.peaktopeakminEditField.Value;
            if ~isempty(value)
                app.filt.ptp_min = value;
            else
                app.filt.ptp_min = 10;
            end
        end

        % Value changed function: negativemagEditField
        function negativemagEditFieldValueChanged(app, event)
            value = app.negativemagEditField.Value;
            if ~isempty(value)
                app.filt.neg_max_amp = value;
            else
                app.filt.neg_max_amp = 20;
            end
        end

        % Value changed function: fullwavedurationEditField, 
        % ...and 1 other component
        function fullwavedurationEditFieldValueChanged(app, event)
            lowval = app.fullwavedurationEditField.Value;
            highval = app.halfwavedurationEditField_5.Value;
            app.filt.whole_dur = [lowval, highval];
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if app.CanonicalsqrButton.Value == 1
                disp('Calculating: Canonical wave with square reference...')
                % adjust the electrode coordinates
                load('ChannelLocs.mat'); % no E1, E2 locations
                c=find(ismember({locs.labels},[app.chlist]));
                locs = locs(c);

                Th = pi/180*[locs.theta];        % Calculate theta values from x,y,z e_loc
                Rd = [locs.radius];              % Calculate radian values from x,y,z e_loc
                
                x = Rd.*cos(Th);                 % Calculate 2D projected X
                y = Rd.*sin(Th);                 % Calculate 2D projected Y
                
                % Squeeze the coordinates into a -0.5 to 0.5 box
                intrad = min(1.0,max(abs(Rd))); intrad = max(intrad,0.5);
                squeezefac = 0.5/intrad;
                x = x * squeezefac; y = y * squeezefac;
                
                % region parameters
                distance_from_center = 0.2; % x and y distance from the center in each direction
                circle_radius = 0.18;
                
                RegionCenters = [...
                    -distance_from_center, -distance_from_center, distance_from_center,  distance_from_center;...
                    distance_from_center, -distance_from_center, -distance_from_center, distance_from_center];
                
                % pre-allocate
                nData = zeros(4, size(app.reref,2));
                canonical_ref = false(1, length(x));
                
                for n = 1 : 4 % Each of the four regions
                    distances = ((x + RegionCenters(1, n)).^ 2 ...
                        + (y + RegionCenters(2, n)).^ 2).^ 0.5;
                    canonical_ref(n, :) = distances < circle_radius;
                    nData(n, :) = mean(app.reref(canonical_ref(n, :), :), 1);
                end

                % filter the canonical wave
                switch app.filt_method
                    case 1 % butter
                        fhc = 0.2/(app.fs/2);
                        flc = 4/(app.fs/2);
                        [b1,a1] = butter(8,fhc,'high');
                        [b2,a2] = butter(8,flc,'low');
                        
                        filtData = filtfilt(b1, a1, nData');
                        filtData = (filtfilt(b2, a2, filtData))';
                    case 3 % cheby 2
                        Wp=[0.2 4]/(app.fs/2); % Filtering parameters
                        Ws=[0.2/5 4*2]/(app.fs/2); % Filtering parameters
                        Rp=3;
                        Rs=15;
                        [n, Wn]=cheb2ord(Wp,Ws,Rp,Rs);
                        [bbp,abp]=cheby2(n,Rs,Wn); % Loses no more than 3 dB in pass band and has at least 10 dB attenuation in stop band
                        
                        filtData=filtfilt(bbp, abp, nData')';
                    otherwise
                        disp('You have to choose different filter method for the canonical wave')
                        filtData = nData;
                end
            elseif app.CanonicaldimButton.Value == 1
                disp('Calculating: Canonical wave with dimond reference...')
                % adjust the electrode coordinates
                load('ChannelLocs.mat'); % no E1, E2 locations
                c=find(ismember({locs.labels},[app.chlist]));
                locs = locs(c);
                Th = pi/180*[locs.theta];        % Calculate theta values from x,y,z e_loc
                Rd = [locs.radius];              % Calculate radian values from x,y,z e_loc
                
                x = Rd.*cos(Th);                 % Calculate 2D projected X
                y = Rd.*sin(Th);                 % Calculate 2D projected Y
                
                % Squeeze the coordinates into a -0.5 to 0.5 box
                intrad = min(1.0,max(abs(Rd))); intrad = max(intrad,0.5);
                squeezefac = 0.5/intrad;
                x = x * squeezefac; y = y * squeezefac;
                
                % region parameters
                distance_from_center = 0.2; % x and y distance from the center in each direction
                circle_radius = 0.21;
                
                RegionCenters = [...
                    distance_from_center, 0, 0, -distance_from_center;...
                    0, distance_from_center -distance_from_center, 0];
                
                % pre-allocate
                nData = zeros(4, size(app.reref,2));
                canonical_ref = false(1, length(x));
                
                for n = 1 : 4 % Each of the four regions
                    distances = ((x + RegionCenters(1, n)).^ 2 ...
                        + (y + RegionCenters(2, n)).^ 2).^ 0.5;
                    canonical_ref(n, :) = distances < circle_radius;
                    nData(n, :) = mean(app.reref(canonical_ref(n, :), :), 1);
                end
                
                % filter the canonical wave
                switch app.filt_method
                    case 1 % butter
                        fhc = 0.3/(app.fs/2);
                        flc = 4/(app.fs/2);
                        [b1,a1] = butter(4,fhc,'high');
                        [b2,a2] = butter(4,flc,'low');
                        
                        filtData = filtfilt(b1, a1, nData');
                        filtData = (filtfilt(b2, a2, filtData))';
                    case 3 % cheby 2
                        Wp=[0.2 4]/(app.fs/2); % Filtering parameters
                        Ws=[0.2/5 4*2]/(app.fs/2); % Filtering parameters
                        Rp=3;
                        Rs=20;
                        [n, Wn]=cheb2ord(Wp,Ws,Rp,Rs);
                        [bbp,abp]=cheby2(n,Rs,Wn); % Loses no more than 3 dB in pass band and has at least 10 dB attenuation in stop band
                        
                        filtData=filtfilt(bbp, abp, nData')';
                    otherwise
                        disp('You have to choose different filter method for the canonical wave')
                        filtData = nData;
                end
            end
            app.cano_dat = filtData;
            assignin('base', 's', app);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            chn = [3:59];%[1:25];
            ts = app.rawdat(chn,(app.taskstart1:app.taskend1));
            if size(ts,2) > size(ts,1)
                ts_resh = detrend(double(ts'));
            else
                ts_resh = detrend(double(ts));
            end
            if app.filt_method == 1 %butterworth
                filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highps/10, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','butter','MatchExactly', 'passband');
                ts_filtered = filtfilt(filter_SW,ts_resh);
                app.filt_dat = ts_resh;
            elseif app.filt_method == 2 % cheby 1
                filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highps/5, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','cheby1','MatchExactly', 'passband');
                ts_filtered = filtfilt(filter_SW,ts_resh);
                app.filt_dat = ts_filtered;
            elseif app.filt_method == 3 % cheby 2
                filter_SW = designfilt('bandpassiir', 'StopbandFrequency1', app.filt.highps/5, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2,'SampleRate', app.fs,'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'DesignMethod','cheby2','MatchExactly', 'passband');
                ts_filtered = filtfilt(filter_SW,ts_resh);
                app.filt_dat = ts_resh;
            else % kaiser
                filter_SW = designfilt('bandpassfir', 'StopbandFrequency1', app.filt.highps/10, 'PassbandFrequency1', app.filt.highps, 'PassbandFrequency2', app.filt.lowps, 'StopbandFrequency2', app.filt.lowps*2, 'StopbandAttenuation1', 1, 'PassbandRipple', .1, 'StopbandAttenuation2', 1, 'SampleRate', app.fs, 'DesignMethod', 'kaiserwin');
                ts_filtered = filtfilt(filter_SW,ts_resh);
                app.filt_dat = ts_filtered;
            end
            app.filttype = 'zx';
            
            disp('Reference: calculating re-reference...')
            if app.GlobalButton_2.Value == 1
                new_ref = mean(app.filt_dat,2);
                % rereference the data to global average
                re_ref = app.filt_dat - repmat(new_ref, [1,size(app.filt_dat, 2)]);
                app.reref = re_ref';
            elseif app.MastoidavgButton_2.Value == 1
                eye_ch = [26,27];
                new_ref = mean(app.filt_dat(:,eye_ch),2);
                re_ref = app.filt_dat - repmat(new_ref, [1,size(app.filt_dat, 2)]);
                re_ref(:,eye_ch) = repmat(new_ref,1,2);
                app.reref = re_ref';
            elseif app.MastoidoppoButton_2.Value == 1
                eye_odd = [26];
                eye_even = [27];
                re_ref = [];
                eeg_odd = [1,3,5,7,9,11,13,15,17,19,21,23,25,27,29,31,33,35,37,39,41,43,45,47,49,51,53,55,57];
                eeg_even = eeg_odd+1;
                re_ref(:,eeg_odd) = app.filt_dat(:,eeg_odd) - repmat(app.filt_dat(:,eye_even), [1,size(app.filt_dat, 2)/2]);
                re_ref(:, eeg_even) = app.filt_dat(:, eeg_even) - repmat(app.filt_dat(:, eye_odd), [1,size(app.filt_dat, 2)/2 ]);
                app.reref = re_ref';
            end
            disp('Re-reference done')
            assignin('base', 's', app);
        end

        % Value changed function: slopeEditField
        function slopeEditFieldValueChanged(app, event)
            value = app.slopeEditField.Value;
            if ~isempty(value)
                app.filt.slope = value;
            else
                app.filt.slope = 3;
            end
        end

        % Value changed function: TimebeforeEditField_2
        function TimebeforeEditField_2ValueChanged(app, event)
            value = app.TimebeforeEditField_2.Value;
            if ~isempty(value)
                app.time_b4 = value;
            else
                app.time_b4 = 1;
            end
        end

        % Value changed function: TimeafterEditField
        function TimeafterEditFieldValueChanged(app, event)
            value = app.TimeafterEditField.Value;
            if ~isempty(value)
                app.time_a8 = value;
            else
                app.time_a8 = 1;
            end
        end

        % Button pushed function: PlotSWButton
        function PlotSWButtonPushed(app, event)
            disp('plotting...')
            if app.usePRED
                k=1;
                while k < size(app.filt_dat,2) 
                if ~isempty(app.slow_waves) && ~isempty(app.slow_waves{k}) 
                %choose slow wave to plot
                    n = find([app.slow_waves{k}.overlap_blink]==0);
                    try
                        for i=1:length(n)
                        n = n(i);
                        n = n([app.slow_waves{k}(n).start]>1 && [app.slow_waves{k}(n).full_sw_dur]>.2); 
                        tso = (app.slow_waves{k}(n).start-app.time_b4):(1/app.fs):(app.slow_waves{k}(n).end+app.time_a8);
                        dso = app.filt_dat';
                        dso = dso(k,round(tso*app.fs));
                        cla(app.SWDisplay,'reset');
                        plot(app.SWDisplay,dso)
                        break
                        end
                    catch
                        disp('no long enough slow waves...')
                    end
                end
                k = k+1;
                end
            elseif app.useFREE
                trusw = [app.slow_waves_ZX.overlap_blink] == 0;
                reg = app.slow_waves_ZX(find(trusw,1,'first')).Region;
                cla(app.SWDisplay,'reset');
                plot(app.SWDisplay,(app.cano_dat(reg,(app.slow_waves_ZX(find(trusw,1,'first')).DownIx-app.time_b4):(app.slow_waves_ZX(find(trusw,1,'first')).UpIx+app.time_a8))));
            else
                error('Must run SW detection first!')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlowdetectionAppUIFigure = uifigure('Visible', 'off');
            app.SlowdetectionAppUIFigure.Name = 'Slow detection App';
            app.SlowdetectionAppUIFigure.Icon = 'icon.png';
            app.TabGroup = uitabgroup(app.SlowdetectionAppUIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.rawdataplot = uiaxes(app.LoadDataTab);
            title(app.rawdataplot, 'Raw data')
            xlabel(app.rawdataplot, 'Time (s)')
            ylabel(app.rawdataplot, 'Frequency')
            app.LoadButton = uibutton(app.LoadDataTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SubjectListBoxLabel = uilabel(app.LoadDataTab);
            app.SubjectListBoxLabel.Text = 'Subject';
            app.SubjectListBox = uilistbox(app.LoadDataTab);
            app.SubjectListBox.Items = {'107', '108', '109', '110', '113', '115', '116', '117', 'len'};
            app.SubjectListBox.ValueChangedFcn = createCallbackFcn(app, @SubjectListBoxValueChanged, true);
            app.SubjectListBox.Value = '107';
            app.BlockEditFieldLabel = uilabel(app.LoadDataTab);
            app.BlockEditFieldLabel.Text = 'Block';
            app.BlockEditField = uieditfield(app.LoadDataTab, 'numeric');
            app.BlockEditField.ValueChangedFcn = createCallbackFcn(app, @BlockEditFieldValueChanged, true);
            app.RunEditFieldLabel = uilabel(app.LoadDataTab);
            app.RunEditFieldLabel.Text = 'Run';
            app.RunEditField = uieditfield(app.LoadDataTab, 'numeric');
            app.RunEditField.ValueChangedFcn = createCallbackFcn(app, @RunEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.LoadDataTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StartEditFieldLabel = uilabel(app.LoadDataTab);
            app.StartEditFieldLabel.Text = 'Start ';
            app.StartEditField = uieditfield(app.LoadDataTab, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.LoadDataTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.LoadDataTab, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.StartEditField_2Label = uilabel(app.LoadDataTab);
            app.StartEditField_2Label.Text = 'Start ';
            app.StartEditField_2 = uieditfield(app.LoadDataTab, 'numeric');
            app.StartEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartEditField_2ValueChanged, true);
            app.EndEditField_2Label = uilabel(app.LoadDataTab);
            app.EndEditField_2Label.Text = 'End';
            app.EndEditField_2 = uieditfield(app.LoadDataTab, 'numeric');
            app.EndEditField_2.ValueChangedFcn = createCallbackFcn(app, @EndEditField_2ValueChanged, true);
            app.SaveButton = uibutton(app.LoadDataTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ChanneltoplotDropDownLabel = uilabel(app.LoadDataTab);
            app.ChanneltoplotDropDownLabel.Text = 'Channel to plot';
            app.ChanneltoplotDropDown = uidropdown(app.LoadDataTab);
            app.ChanneltoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @ChanneltoplotDropDownValueChanged, true);
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.SizeChangedFcn = createCallbackFcn(app, @PSDTabSizeChanged, true);
            app.PSDTab.Title = 'PSD';
            app.sec1axis = uiaxes(app.PSDTab);
            title(app.sec1axis, 'Chunk 1')
            xlabel(app.sec1axis, 'X')
            ylabel(app.sec1axis, 'Y')
            app.sec2axis = uiaxes(app.PSDTab);
            title(app.sec2axis, 'Chunk 2')
            xlabel(app.sec2axis, 'X')
            ylabel(app.sec2axis, 'Y')
            app.DataButtonGroup = uibuttongroup(app.PSDTab);
            app.DataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataButtonGroupSelectionChanged, true);
            app.DataButtonGroup.Title = 'Data ';
            app.timeseriesButton = uiradiobutton(app.DataButtonGroup);
            app.timeseriesButton.Text = 'time series';
            app.timeseriesButton.Value = true;
            app.PSDButton = uiradiobutton(app.DataButtonGroup);
            app.PSDButton.Text = 'PSD';
            app.DataDisplayButtonGroup_2 = uibuttongroup(app.PSDTab);
            app.DataDisplayButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @DataDisplayButtonGroup_2SelectionChanged, true);
            app.DataDisplayButtonGroup_2.Title = 'Data Display';
            app.timeseriesButton_2 = uiradiobutton(app.DataDisplayButtonGroup_2);
            app.timeseriesButton_2.Text = 'time series';
            app.timeseriesButton_2.Value = true;
            app.PSDButton_2 = uiradiobutton(app.DataDisplayButtonGroup_2);
            app.PSDButton_2.Text = 'PSD';
            app.GOButton = uibutton(app.PSDTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.GOButton_2 = uibutton(app.PSDTab, 'push');
            app.GOButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOButton_2Pushed, true);
            app.GOButton_2.Text = 'GO';
            app.SaveButton_2 = uibutton(app.PSDTab, 'push');
            app.SaveButton_2.Text = 'Save';
            app.SaveButton_3 = uibutton(app.PSDTab, 'push');
            app.SaveButton_3.Text = 'Save';
            app.SWdetectionTab_2 = uitab(app.TabGroup);
            app.SWdetectionTab_2.Title = 'SW detection';
            app.BlinkDisplay = uiaxes(app.SWdetectionTab_2);
            title(app.BlinkDisplay, 'Blinks')
            ylabel(app.BlinkDisplay, 'Y')
            app.BlinkDisplay.ButtonDownFcn = createCallbackFcn(app, @BlockEditFieldValueChanged, true);
            app.SWDisplay = uiaxes(app.SWdetectionTab_2);
            title(app.SWDisplay, 'Single slow wave')
            xlabel(app.SWDisplay, 'X')
            app.SWDisplay.ButtonDownFcn = createCallbackFcn(app, @BlockEditFieldValueChanged, true);
            app.UsepredefinedsettingsButtonGroup_2 = uibuttongroup(app.SWdetectionTab_2);
            app.UsepredefinedsettingsButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @UsepredefinedsettingsButtonGroup_2SelectionChanged, true);
            app.UsepredefinedsettingsButtonGroup_2.Title = 'Use pre-defined settings';
            app.Massimini2004Button_2 = uiradiobutton(app.UsepredefinedsettingsButtonGroup_2);
            app.Massimini2004Button_2.Text = 'Massimini2004';
            app.AASMButton_2 = uiradiobutton(app.UsepredefinedsettingsButtonGroup_2);
            app.AASMButton_2.Text = 'AASM';
            app.AASMButton_2.Value = true;
            app.Ngo2015Button_2 = uiradiobutton(app.UsepredefinedsettingsButtonGroup_2);
            app.Ngo2015Button_2.Text = 'Ngo2015';
            app.Staresina2015Button_2 = uiradiobutton(app.UsepredefinedsettingsButtonGroup_2);
            app.Staresina2015Button_2.Text = 'Staresina2015';
            app.Fattinger2017Button = uiradiobutton(app.UsepredefinedsettingsButtonGroup_2);
            app.Fattinger2017Button.Text = 'Fattinger2017';
            app.Hung2013Button = uiradiobutton(app.UsepredefinedsettingsButtonGroup_2);
            app.Hung2013Button.Text = 'Hung2013';
            app.UserdefinedparametersTextArea_2Label = uilabel(app.SWdetectionTab_2);
            app.UserdefinedparametersTextArea_2Label.Text = 'User defined parameters';
            app.UserdefinedparametersTextArea_2 = uitextarea(app.SWdetectionTab_2);
            app.lowpassfilterEditField_2Label = uilabel(app.SWdetectionTab_2);
            app.lowpassfilterEditField_2Label.Text = 'lowpass filter';
            app.lowpassfilterEditField_2 = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.lowpassfilterEditField_2.ValueChangedFcn = createCallbackFcn(app, @lowpassfilterEditField_2ValueChanged, true);
            app.lowpassfilterEditField_2.Value = 4.5;
            app.CheckBox_3 = uicheckbox(app.SWdetectionTab_2);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.highpassfilterEditFieldLabel = uilabel(app.SWdetectionTab_2);
            app.highpassfilterEditFieldLabel.Text = 'highpass filter';
            app.highpassfilterEditField = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.highpassfilterEditField.ValueChangedFcn = createCallbackFcn(app, @highpassfilterEditFieldValueChanged, true);
            app.highpassfilterEditField.Value = 0.5;
            app.RunBlinkDetectionButton = uibutton(app.SWdetectionTab_2, 'push');
            app.RunBlinkDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunBlinkDetectionButtonPushed, true);
            app.RunBlinkDetectionButton.Text = 'Run Blink Detection';
            app.E1CheckBox = uicheckbox(app.SWdetectionTab_2);
            app.E1CheckBox.ValueChangedFcn = createCallbackFcn(app, @E1CheckBoxValueChanged, true);
            app.E1CheckBox.Text = 'E1';
            app.E2CheckBox = uicheckbox(app.SWdetectionTab_2);
            app.E2CheckBox.ValueChangedFcn = createCallbackFcn(app, @E2CheckBoxValueChanged, true);
            app.E2CheckBox.Text = 'E2';
            app.Fp1CheckBox = uicheckbox(app.SWdetectionTab_2);
            app.Fp1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Fp1CheckBoxValueChanged, true);
            app.Fp1CheckBox.Text = 'Fp1';
            app.Fp2CheckBox = uicheckbox(app.SWdetectionTab_2);
            app.Fp2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Fp2CheckBoxValueChanged, true);
            app.Fp2CheckBox.Text = 'Fp2';
            app.RunSWdetectionButton = uibutton(app.SWdetectionTab_2, 'push');
            app.RunSWdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunSWdetectionButtonPushed, true);
            app.RunSWdetectionButton.Text = 'Run SW detection';
            app.SaveSWButton = uibutton(app.SWdetectionTab_2, 'push');
            app.SaveSWButton.Text = 'Save SW';
            app.ThresholdSliderLabel = uilabel(app.SWdetectionTab_2);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.SWdetectionTab_2);
            app.ThresholdSlider.Limits = [0 3];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.halfwavedurationsEditFieldLabel = uilabel(app.SWdetectionTab_2);
            app.halfwavedurationsEditFieldLabel.Text = 'half-wave duration (s)';
            app.halfwavedurationsEditField = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.halfwavedurationsEditField.ValueChangedFcn = createCallbackFcn(app, @halfwavedurationEditFieldValueChanged, true);
            app.halfwavedurationsEditField.Value = 0.5;
            app.peaktopeakminEditFieldLabel = uilabel(app.SWdetectionTab_2);
            app.peaktopeakminEditFieldLabel.Text = 'peak to peak min';
            app.peaktopeakminEditField = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.peaktopeakminEditField.ValueChangedFcn = createCallbackFcn(app, @peaktopeakminEditFieldValueChanged, true);
            app.filtermethodDropDownLabel = uilabel(app.SWdetectionTab_2);
            app.filtermethodDropDownLabel.Text = 'filter method';
            app.filtermethodDropDown = uidropdown(app.SWdetectionTab_2);
            app.filtermethodDropDown.ValueChangedFcn = createCallbackFcn(app, @filtermethodDropDownValueChanged, true);
            app.CheckBox_4 = uicheckbox(app.SWdetectionTab_2);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.ClearSWButton = uibutton(app.SWdetectionTab_2, 'push');
            app.ClearSWButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSWButtonPushed, true);
            app.ClearSWButton.Text = 'Clear SW';
            app.negativemagEditFieldLabel = uilabel(app.SWdetectionTab_2);
            app.negativemagEditFieldLabel.Text = 'negative mag';
            app.negativemagEditField = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.negativemagEditField.ValueChangedFcn = createCallbackFcn(app, @negativemagEditFieldValueChanged, true);
            app.halfwavedurationEditField_3 = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.halfwavedurationEditField_3.ValueChangedFcn = createCallbackFcn(app, @halfwavedurationEditFieldValueChanged, true);
            app.halfwavedurationEditField_3.Value = 10;
            app.fullwavedurationEditFieldLabel = uilabel(app.SWdetectionTab_2);
            app.fullwavedurationEditFieldLabel.Text = 'full wave duration';
            app.fullwavedurationEditField = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.fullwavedurationEditField.ValueChangedFcn = createCallbackFcn(app, @fullwavedurationEditFieldValueChanged, true);
            app.fullwavedurationEditField.Value = 0.75;
            app.halfwavedurationEditField_5 = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.halfwavedurationEditField_5.ValueChangedFcn = createCallbackFcn(app, @fullwavedurationEditFieldValueChanged, true);
            app.halfwavedurationEditField_5.Value = 0.75;
            app.ButtonGroup = uibuttongroup(app.SWdetectionTab_2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.CanonicalsqrButton = uitogglebutton(app.ButtonGroup);
            app.CanonicalsqrButton.Text = 'Canonical-sqr';
            app.CanonicalsqrButton.Value = true;
            app.CanonicaldimButton = uitogglebutton(app.ButtonGroup);
            app.CanonicaldimButton.Text = 'Canonical-dim';
            app.AllchannelsButton = uitogglebutton(app.ButtonGroup);
            app.AllchannelsButton.Text = 'All channels';
            app.halfwavedurationEditFieldLabel_2 = uilabel(app.SWdetectionTab_2);
            app.halfwavedurationEditFieldLabel_2.Text = '~';
            app.ButtonGroup_2 = uibuttongroup(app.SWdetectionTab_2);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.GlobalButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.GlobalButton_2.Text = 'Global';
            app.GlobalButton_2.Value = true;
            app.MastoidavgButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.MastoidavgButton_2.Text = 'Mastoid-avg';
            app.MastoidoppoButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.MastoidoppoButton_2.Text = 'Mastoid-oppo';
            app.halfwavedurationEditFieldLabel_3 = uilabel(app.SWdetectionTab_2);
            app.halfwavedurationEditFieldLabel_3.Text = '~';
            app.slopeEditFieldLabel = uilabel(app.SWdetectionTab_2);
            app.slopeEditFieldLabel.Text = 'slope';
            app.slopeEditField = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.slopeEditField.ValueChangedFcn = createCallbackFcn(app, @slopeEditFieldValueChanged, true);
            app.TimeafterEditFieldLabel = uilabel(app.SWdetectionTab_2);
            app.TimeafterEditFieldLabel.Text = 'Time after';
            app.TimeafterEditField = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.TimeafterEditField.ValueChangedFcn = createCallbackFcn(app, @TimeafterEditFieldValueChanged, true);
            app.TimebeforeEditField_2Label = uilabel(app.SWdetectionTab_2);
            app.TimebeforeEditField_2Label.Text = 'Time before ';
            app.TimebeforeEditField_2 = uieditfield(app.SWdetectionTab_2, 'numeric');
            app.TimebeforeEditField_2.ValueChangedFcn = createCallbackFcn(app, @TimebeforeEditField_2ValueChanged, true);
            app.PlotSWButton = uibutton(app.SWdetectionTab_2, 'push');
            app.PlotSWButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSWButtonPushed, true);
            app.PlotSWButton.Text = 'Plot SW';
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.Panel = uipanel(app.ResultTab);
            app.Panel.Title = 'Panel';
            app.ContextMenu = uicontextmenu(app.SlowdetectionAppUIFigure);
            app.passbandstopbandMenu = uimenu(app.ContextMenu);
            app.passbandstopbandMenu.Text = 'passband, stopband';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.lowpassfilterEditField_2.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.SlowdetectionAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REST_ls

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlowdetectionAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlowdetectionAppUIFigure)
        end
    end
end